classdef mainGame < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        QuitButton              matlab.ui.control.Button
        Bet1EditFieldLabel      matlab.ui.control.Label
        Bet1EditField           matlab.ui.control.NumericEditField
        Balance1EditFieldLabel  matlab.ui.control.Label
        Balance1EditField       matlab.ui.control.NumericEditField
        Bet2EditFieldLabel      matlab.ui.control.Label
        Bet2EditField           matlab.ui.control.NumericEditField
        Balance2EditFieldLabel  matlab.ui.control.Label
        Balance2EditField       matlab.ui.control.NumericEditField
        Image                   matlab.ui.control.Image
        Image2                  matlab.ui.control.Image
        Image3                  matlab.ui.control.Image
        Image4                  matlab.ui.control.Image
        Image5                  matlab.ui.control.Image
        Image6                  matlab.ui.control.Image
        Image_2                 matlab.ui.control.Image
        Image2_2                matlab.ui.control.Image
        Image3_2                matlab.ui.control.Image
        Image4_2                matlab.ui.control.Image
        Image5_2                matlab.ui.control.Image
        Image6_2                matlab.ui.control.Image
        Image_3                 matlab.ui.control.Image
        Image2_3                matlab.ui.control.Image
        Image3_3                matlab.ui.control.Image
        Image4_3                matlab.ui.control.Image
        Image5_3                matlab.ui.control.Image
        Image6_3                matlab.ui.control.Image
        HitButton               matlab.ui.control.Button
        StandButton             matlab.ui.control.Button
        DoubleButton            matlab.ui.control.Button
        SplitButton             matlab.ui.control.Button
        HitButton_2             matlab.ui.control.Button
        StandButton_2           matlab.ui.control.Button
        DoubleButton_2          matlab.ui.control.Button
        SplitButton_2           matlab.ui.control.Button
    end

    
    properties (Access = public)
        CallingApp % Description
        img
        testA = 10
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, startApp, numPlayers, bet1, balance1, bet2, balance2)
            app.CallingApp = startApp;
            app.Bet1EditField.Value = bet1;
            app.Balance1EditField.Value = balance1;
            app.Bet2EditField.Value = bet2;
            app.Balance2EditField.Value = balance2;
            
           % Show only the inital hand
            app.Image3.Visible = 'off';
            app.Image4.Visible = 'off';
            app.Image5.Visible = 'off';
            app.Image6.Visible = 'off';
            
            app.Image3_3.Visible = 'off';
            app.Image4_3.Visible = 'off';
            app.Image5_3.Visible = 'off';
            app.Image6_3.Visible = 'off';
            
                app.Image3_2.Visible = 'off';
                app.Image4_2.Visible = 'off';
                app.Image5_2.Visible = 'off';
                app.Image6_2.Visible = 'off';
            
            if(numPlayers == 1)
                app.Bet2EditField.Visible = 'off';
                app.Bet2EditFieldLabel.Visible = 'off';
                app.Balance2EditField.Visible = 'off';
                app.Balance2EditFieldLabel.Visible = 'off';
                
                app.HitButton_2.Visible = 'off';
                app.StandButton_2.Visible = 'off';
                app.DoubleButton_2.Visible = 'off';
                app.SplitButton_2.Visible = 'off';
                
                app.Image_2.Visible = 'off';
                app.Image2_2.Visible = 'off';
            end
        end

        % Callback function
        function ButtonPushed(app, event)
            cardDeck(app)     
            app.Image2.ImageSource = "cardPhotos/queen_of_hearts.png";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.QuitButton = uibutton(app.UIFigure, 'push');
            app.QuitButton.Text = 'Quit';
            app.Bet1EditFieldLabel = uilabel(app.UIFigure);
            app.Bet1EditFieldLabel.Text = 'Bet 1';
            app.Bet1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Balance1EditFieldLabel = uilabel(app.UIFigure);
            app.Balance1EditFieldLabel.Text = 'Balance 1';
            app.Balance1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Balance1EditField.Value = 2000;
            app.Bet2EditFieldLabel = uilabel(app.UIFigure);
            app.Bet2EditFieldLabel.Text = 'Bet 2';
            app.Bet2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Balance2EditFieldLabel = uilabel(app.UIFigure);
            app.Balance2EditFieldLabel.Text = 'Balance 2';
            app.Balance2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Balance2EditField.Value = 2000;
            app.Image = uiimage(app.UIFigure);
            app.Image2 = uiimage(app.UIFigure);
            app.Image3 = uiimage(app.UIFigure);
            app.Image4 = uiimage(app.UIFigure);
            app.Image5 = uiimage(app.UIFigure);
            app.Image6 = uiimage(app.UIFigure);
            app.Image_2 = uiimage(app.UIFigure);
            app.Image2_2 = uiimage(app.UIFigure);
            app.Image3_2 = uiimage(app.UIFigure);
            app.Image4_2 = uiimage(app.UIFigure);
            app.Image5_2 = uiimage(app.UIFigure);
            app.Image6_2 = uiimage(app.UIFigure);
            app.Image_3 = uiimage(app.UIFigure);
            app.Image2_3 = uiimage(app.UIFigure);
            app.Image3_3 = uiimage(app.UIFigure);
            app.Image4_3 = uiimage(app.UIFigure);
            app.Image5_3 = uiimage(app.UIFigure);
            app.Image6_3 = uiimage(app.UIFigure);
            app.HitButton = uibutton(app.UIFigure, 'push');
            app.HitButton.Text = 'Hit';
            app.StandButton = uibutton(app.UIFigure, 'push');
            app.StandButton.Text = 'Stand';
            app.DoubleButton = uibutton(app.UIFigure, 'push');
            app.DoubleButton.Text = 'Double';
            app.SplitButton = uibutton(app.UIFigure, 'push');
            app.SplitButton.Text = 'Split';
            app.HitButton_2 = uibutton(app.UIFigure, 'push');
            app.HitButton_2.Text = 'Hit';
            app.StandButton_2 = uibutton(app.UIFigure, 'push');
            app.StandButton_2.Text = 'Stand';
            app.DoubleButton_2 = uibutton(app.UIFigure, 'push');
            app.DoubleButton_2.Text = 'Double';
            app.SplitButton_2 = uibutton(app.UIFigure, 'push');
            app.SplitButton_2.Text = 'Split';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mainGame(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end